classdef ImageAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PauseContinueButton        matlab.ui.control.StateButton
        StopButton                 matlab.ui.control.StateButton
        ShutdownCCDButton          matlab.ui.control.Button
        SelectCameraDropDown       matlab.ui.control.DropDown
        SelectCameraDropDownLabel  matlab.ui.control.Label
        InitializeCCDButton        matlab.ui.control.Button
        SaveAllButton              matlab.ui.control.Button
        SaveCurrentButton          matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function start(app, event)
            num_image = 0;
            while ~app.StopButton.Value

                while ~app.PauseContinueButton.Value && ~app.StopButton.Value
                    tic
                    fprintf('Acquiring CCD image...\n')
                    
                    image = acquireCCDImage("num_images", 1, "timeout", 30);
                    num_image = num_image + 1;
                    
                    imagesc(app.UIAxes, image)
                    app.UIAxes.DataAspectRatio = [1, 1, 1];
                    app.UIAxes.Title.String = sprintf('Number of image: %d', num_image);
                    drawnow

                    fprintf('Acquisition complete, number of images : %d\n', num_image);
                    pause(0.1)
                    toc
                end

                pause(0.1)
            end

            fprintf('Acquisition stopped\n')
            app.StopButton.Value = false;
        end

        % Button pushed function: SaveAllButton
        function save_all(app, event)
            
        end

        % Button pushed function: SaveCurrentButton
        function save_current(app, event)
            
        end

        % Button pushed function: InitializeCCDButton
        function initialize_ccd(app, event)
            initializeCCD
            select_ccd(app, event)
            setDMDLive(0.1)
        end

        % Button pushed function: ShutdownCCDButton
        function shutdown_ccd(app, event)
            shutDownCCD
        end

        % Value changed function: SelectCameraDropDown
        function select_ccd(app, event)
            value = app.SelectCameraDropDown.Value;
            switch value
                case 'Upper CCD: 19330'
                    setCurrentCCD(19330)
                case 'Lower CCD: 19331'
                    setCurrentCCD(19331)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Number of image:')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start';
            app.SaveCurrentButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @save_current, true);
            app.SaveCurrentButton.Text = {'Save Current'; ''};
            app.SaveAllButton = uibutton(app.UIFigure, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @save_all, true);
            app.SaveAllButton.Text = {'Save All'; ''};
            app.InitializeCCDButton = uibutton(app.UIFigure, 'push');
            app.InitializeCCDButton.ButtonPushedFcn = createCallbackFcn(app, @initialize_ccd, true);
            app.InitializeCCDButton.Text = 'Initialize CCD';
            app.SelectCameraDropDownLabel = uilabel(app.UIFigure);
            app.SelectCameraDropDownLabel.Text = 'Select Camera';
            app.SelectCameraDropDown = uidropdown(app.UIFigure);
            app.SelectCameraDropDown.Items = {'Upper CCD: 19330', 'Lower CCD: 19331'};
            app.SelectCameraDropDown.ValueChangedFcn = createCallbackFcn(app, @select_ccd, true);
            app.SelectCameraDropDown.Value = 'Upper CCD: 19330';
            app.ShutdownCCDButton = uibutton(app.UIFigure, 'push');
            app.ShutdownCCDButton.ButtonPushedFcn = createCallbackFcn(app, @shutdown_ccd, true);
            app.ShutdownCCDButton.Text = 'Shut down CCD';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.PauseContinueButton = uibutton(app.UIFigure, 'state');
            app.PauseContinueButton.Text = 'Pause / Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end